classdef Armaduras_3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NmerodeelementosLabel       matlab.ui.control.Label
        NumeroElementosTexto        matlab.ui.control.NumericEditField
        NmerodenodosEditFieldLabel  matlab.ui.control.Label
        NumeroNodosTexto            matlab.ui.control.NumericEditField
        TablaElementos              matlab.ui.control.Table
        TablaNodos                  matlab.ui.control.Table
        TablaInfo                   matlab.ui.control.Table
        NmerodefuerzasLabel         matlab.ui.control.Label
        NumeroFuerzasTexto          matlab.ui.control.NumericEditField
        TablaFuerzas                matlab.ui.control.Table
        ResolverButton              matlab.ui.control.Button
        EConstanteCheckBox          matlab.ui.control.CheckBox
        AConstanteCheckBox          matlab.ui.control.CheckBox
        EEditFieldLabel             matlab.ui.control.Label
        EEditField                  matlab.ui.control.NumericEditField
        AEditFieldLabel             matlab.ui.control.Label
        AEditField                  matlab.ui.control.NumericEditField
        TablaCondiciones            matlab.ui.control.Table
    end

    
    properties (Access = private)
        DatosBase1 %Elementos
        DatosBase2 %Nodos
        DatosBase3 %Info
        DatosBase4 %Fuerzas
        DatosBase5 %Condiciones
        valueE
        valueN
        valueF
        valueME
        valueA
        Eactive
        Aactive
    end
    
    methods (Access = private)
        
        function results = updateE(app)
            vectorE=ones(app.valueE,1).*app.valueME;
            app.TablaInfo.Data(:,2)=vectorE;
        end
        
        function results = updateA(app)
            vectorA=ones(app.valueE,1).*app.valueA
            app.TablaInfo.Data(:,3)=vectorA;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TablaElementos.Data=zeros(1,3);
            app.TablaNodos.Data=cell(1,5);
            app.TablaInfo.Data=zeros(1,5);
            app.TablaFuerzas.Data=zeros(1,5);
            app.TablaCondiciones.Data=cell(1,4);
            app.valueE=1;
            app.valueME=0;
            app.valueA=0;
            app.TablaNodos.ColumnFormat = {'numeric','logical','numeric','numeric','numeric'};
            app.TablaCondiciones.ColumnFormat={'numeric','logical','logical','logical'};
%             app.TablaInfo.
        end

        % Value changed function: NumeroElementosTexto
        function NumeroElementosTextoValueChanged(app, event)
            app.valueE = app.NumeroElementosTexto.Value;
            N=1:app.valueE;
            [m, n] = size(app.TablaElementos.Data); 
            if app.valueE>m
                app.DatosBase1 = app.TablaElementos.Data; 
                d = zeros(app.valueE, n);
                d(1:m, 1:n) = app.DatosBase1;
                app.TablaElementos.Data = d;
                app.TablaElementos.Data(:,1)=N';
            else
                app.DatosBase1 = app.TablaElementos.Data(1:app.valueE,:); 
                d = zeros(app.valueE, n);
                d(1:app.valueE, 1:n) = app.DatosBase1;
                app.TablaElementos.Data = d;
                app.TablaElementos.Data(:,1)=N';
            end
            
            [m, n] = size(app.TablaInfo.Data);
            if app.valueE>m
                app.DatosBase3= app.TablaInfo.Data; 
                d = zeros(app.valueE, n);
                d(1:m, 1:n) = app.DatosBase3;
                app.TablaInfo.Data = d;
                app.TablaInfo.Data(:,1)=N';
            else 
                app.DatosBase3 = app.TablaInfo.Data(1:app.valueE,:); 
                d = zeros(app.valueE, n);
                d(1:app.valueE, 1:n) = app.DatosBase3;
                app.TablaInfo.Data = d;
                app.TablaInfo.Data(:,1)=N';
            end
            
            if app.Eactive==1
                updateE(app)
            end
            if app.Aactive==1
                updateA(app)
            end
        end

        % Value changed function: NumeroNodosTexto
        function NumeroNodosTextoValueChanged(app, event)
            app.valueN = app.NumeroNodosTexto.Value;
            N=num2cell(1:app.valueN);
            [m, n] = size(app.TablaNodos.Data);
            if app.valueN>m
                app.DatosBase2 = app.TablaNodos.Data;
                d = cell(app.valueN, n);
                d(1:m, 3:4) = app.DatosBase2(:,3:4);
                app.TablaNodos.Data = d;
                app.TablaNodos.Data(:,1)=N';
            else
                app.DatosBase2 = app.TablaNodos.Data(1:app.valueN,:); 
                d = cell(app.valueN, n);
                d(1:app.valueN, 1:n) = app.DatosBase2;
                app.TablaNodos.Data = d;
                app.TablaNodos.Data(:,1)=N';
            end
            
           [o,p]=size(app.TablaCondiciones.Data);
           if app.valueN>o
                app.DatosBase5 = app.TablaCondiciones.Data;
                d = cell(app.valueN, p);
                d(1:o, 1:p) = app.DatosBase5;
                app.TablaCondiciones.Data = d;
                app.TablaCondiciones.Data(:,1)=N';
           else
                app.DatosBase5 = app.TablaCondiciones.Data(1:app.valueN,:); 
                d = cell(app.valueN, p);
                d(1:app.valueN, 1:p) = app.DatosBase5;
                app.TablaCondiciones.Data = d;
                app.TablaCondiciones.Data(:,1)=N';
           end
        end

        % Value changed function: NumeroFuerzasTexto
        function NumeroFuerzasTextoValueChanged(app, event)
            app.valueF = app.NumeroFuerzasTexto.Value;
            N=1:app.valueF;
            [m, n] = size(app.TablaFuerzas.Data);
            if app.valueF>m
                app.DatosBase4 = app.TablaFuerzas.Data; 
                d = zeros(app.valueF, n);
                d(1:m, 1:n) = app.DatosBase4;
                app.TablaFuerzas.Data = d;
                app.TablaFuerzas.Data(:,1)=N';
            else
                app.DatosBase4 = app.TablaFuerzas.Data(1:app.valueF,:); 
                d = zeros(app.valueF, n);
                d(1:app.valueF, 1:n) = app.DatosBase4;
                app.TablaFuerzas.Data = d;
                app.TablaFuerzas.Data(:,1)=N';
            end
        end

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)
clc

n=app.valueE;
uf=sort(app.TablaFuerzas.Data(:,2))';
E=app.TablaInfo.Data(:,2)';
A=app.TablaInfo.Data(:,3)';
coo=cell2mat(app.TablaNodos.Data(:,3:5));
cnx=app.TablaElementos.Data(:,2:3);
Z=app.TablaNodos.Data(:,2);
for i=1:length(Z)
    if Z{i}==1
        z(i)=1;
    else
        z(i)=0;
    end
end
nf=find(z);
COND=app.TablaCondiciones.Data(:,2:4)';
for i=1:app.valueN*3
    if COND{i}==1
        ce(i)=1;
    else
        ce(i)=0;
    end
end
for i=1:app.valueF
    F(3*i-2)=app.TablaFuerzas.Data(i,3);
    F(3*i-1)=app.TablaFuerzas.Data(i,4);
    F(3*i)=app.TablaFuerzas.Data(i,5);
end

%Procesamiento de datos y cálculo de resultados
nn=app.valueN;        %número de nodos
syms(sym('fx', [1, nn]))
syms(sym('fy', [1, nn]))
syms(sym('fz', [1, nn]))
syms(sym('ux', [1, nn]))
syms(sym('uy', [1, nn]))
syms(sym('uz', [1, nn]))
fx=sym('fx', [1,nn]);
fy=sym('fy', [1,nn]);
fz=sym('fz', [1,nn]);
ux=sym('ux', [1,nn]);
uy=sym('uy', [1,nn]);
uz=sym('uz', [1,nn]);
V=sort([uf nf]);
nodes=1:nn;
dVn=length(nodes)-length(V);
for i=1:nn
    if isequal(V,nodes(1:end-dVn))==1
        V(end+1:end+dVn)=0;
    elseif i==nn && length(V)<nn
        V(end+1)=0;
    elseif V(i)~=nodes(i) && V(i)~=0
        V=[V(1:i-1) 0 V(i:end)];
    end
end

fV=find(~V);
fx(fV)=0;
fy(fV)=0;
fz(fV)=0;
R=zeros(3*nn,1);
for i=1:length(uf)
    fx(uf(i))=F(3*i-2);
    fy(uf(i))=F(3*i-1);
    fz(uf(i))=F(3*i);
    R(3*uf(i)-2:3*(uf(i)))=F(3*i-2:3*i);
end
ux(nf)=0;
uy(nf)=0;
uz(nf)=0;

for i=1:nn
    f(3*i-2)=fx(i);
    f(3*i-1)=fy(i);
    f(3*i)=fz(i);
    u(3*i-2)=ux(i);
    u(3*i-1)=uy(i);
    u(3*i)=uz(i);
end
ut=arrayfun(@string, u);

for i=1:n
    v=cnx(i,2);
    w=cnx(i,1);
    L(i)=sqrt((coo(v,1)-coo(w,1))^2+(coo(v,2)-coo(w,2))^2+(coo(v,3)-coo(w,3))^2);
end

for i=1:3
    for j=1:n
        v=cnx(j,2);
        w=cnx(j,1);
        cs(j,i)=(coo(v,i)-coo(w,i))/L(j);
    end
end

MC=zeros(3*nn);
mc=cell(1,n);
I=0;

for i=1:n
    k(i)=E(i)*A(i)/L(i);
    info(i,:)=[E(i) A(i) L(i) k(i)];
    lambda{i}=[cs(i,1)^2 cs(i,1)*cs(i,2) cs(i,1)*cs(i,3);
               cs(i,1)*cs(i,2) cs(i,2)^2 cs(i,2)*cs(i,3);
               cs(i,1)*cs(i,3) cs(i,2)*cs(i,3) cs(i,3)^2];
    M{i}=k(i)*[lambda{i} -lambda{i}; -lambda{i} lambda{i}];
    NL1(i)=cnx(i,1);
    NL2(i)=cnx(i,2);
    mc{i}=zeros(3*nn);
    mc{i}(3*NL1(i)-2:3*NL1(i),3*NL1(i)-2:3*NL1(i))=k(i)*lambda{i};
    mc{i}(3*NL1(i)-2:3*NL1(i),3*NL2(i)-2:3*NL2(i))=k(i)*-lambda{i};
    mc{i}(3*NL2(i)-2:3*NL2(i),3*NL1(i)-2:3*NL1(i))=k(i)*-lambda{i};
    mc{i}(3*NL2(i)-2:3*NL2(i),3*NL2(i)-2:3*NL2(i))=k(i)*lambda{i};
    MC=MC+mc{i};
end

u(find(u))=1;
u(find(ce))=0;
us=find(u);
MF=double([MC(us,us) R(us)]);
S=rref(MF);

RR=S(:,end)';
for i=1:length(us)
    fprintf(ut(us(i)))
    fprintf(': \n')
    display(RR(i))
end
u(us)=RR;
% format shorteng
for i=1:n
    delta(i)=[-cs(i,:) cs(i,:)]*[u(3*NL1(i)-2) u(3*NL1(i)-1) u(3*NL1(i)) u(3*NL2(i)-2) u(3*NL2(i)-1) u(3*NL2(i))]';
    sigma(i)=k(i)/A(i)*delta(i);
end
delta=double(delta);
sigma=double(sigma);
strain=delta'./L';
Tabla=array2table([(1:n)' strain sigma'], 'VariableNames', {'Element','Strain', 'Stress'});
disp(Tabla)
        end

        % Value changed function: EConstanteCheckBox
        function EConstanteCheckBoxValueChanged(app, event)
            app.Eactive = app.EConstanteCheckBox.Value;
            if app.Eactive==1
                set(app.EEditField, 'Enable', 'on')
                updateE(app);
            else 
                set(app.EEditField, 'Enable', 'off')
            end
        end

        % Value changed function: AConstanteCheckBox
        function AConstanteCheckBoxValueChanged(app, event)
            app.Aactive = app.AConstanteCheckBox.Value;
            if app.Aactive==1
                set(app.AEditField, 'Enable', 'on')
                updateA(app);
            else 
                set(app.AEditField, 'Enable', 'off')
            end
        end

        % Value changed function: EEditField
        function EEditFieldValueChanged(app, event)
            app.valueME = app.EEditField.Value;
            updateE(app);
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            app.valueA = app.AEditField.Value;
            updateA(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NmerodeelementosLabel = uilabel(app.UIFigure);
            app.NmerodeelementosLabel.Text = {'Número de '; 'elementos'};
            app.NumeroElementosTexto = uieditfield(app.UIFigure, 'numeric');
            app.NumeroElementosTexto.Limits = [0 Inf];
            app.NumeroElementosTexto.RoundFractionalValues = 'on';
            app.NumeroElementosTexto.ValueChangedFcn = createCallbackFcn(app, @NumeroElementosTextoValueChanged, true);
            app.NmerodenodosEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodenodosEditFieldLabel.Text = {'Número de '; 'nodos'};
            app.NumeroNodosTexto = uieditfield(app.UIFigure, 'numeric');
            app.NumeroNodosTexto.Limits = [0 Inf];
            app.NumeroNodosTexto.RoundFractionalValues = 'on';
            app.NumeroNodosTexto.ValueChangedFcn = createCallbackFcn(app, @NumeroNodosTextoValueChanged, true);
            app.TablaElementos = uitable(app.UIFigure);
            app.TablaElementos.ColumnName = {'Elemento'; 'NL1'; 'NL2'};
            app.TablaElementos.RowName = {};
            app.TablaElementos.ColumnEditable = [false true true];
            app.TablaElementos.Tooltip = {'Indica los nodos con los que cada elemento se conecta.'};
            app.TablaNodos = uitable(app.UIFigure);
            app.TablaNodos.ColumnName = {'Nodo'; 'Fijo'; 'x'; 'y'; 'z'};
            app.TablaNodos.RowName = {};
            app.TablaNodos.ColumnEditable = [false true true true true];
            app.TablaNodos.Tooltip = {'Fijo - ¿el nodo se encuentra empotrado/fijo? Selecciona los que sí y deja en blanco los que no.'; 'x y z - ¿cuáles son las coordenadas del nodo?.'};
            app.TablaInfo = uitable(app.UIFigure);
            app.TablaInfo.ColumnName = {'Elemento'; 'Módulo Elástico'; 'A'; 'L'; 'k'};
            app.TablaInfo.RowName = {};
            app.TablaInfo.ColumnEditable = [false true true false false];
            app.NmerodefuerzasLabel = uilabel(app.UIFigure);
            app.NmerodefuerzasLabel.Text = {'Número de '; 'fuerzas'};
            app.NumeroFuerzasTexto = uieditfield(app.UIFigure, 'numeric');
            app.NumeroFuerzasTexto.Limits = [0 Inf];
            app.NumeroFuerzasTexto.RoundFractionalValues = 'on';
            app.NumeroFuerzasTexto.ValueChangedFcn = createCallbackFcn(app, @NumeroFuerzasTextoValueChanged, true);
            app.TablaFuerzas = uitable(app.UIFigure);
            app.TablaFuerzas.ColumnName = {'Fuerza'; 'Nodo'; 'x'; 'y'; 'z'};
            app.TablaFuerzas.RowName = {};
            app.TablaFuerzas.ColumnEditable = [false true true true true];
            app.TablaFuerzas.Tooltip = {'Nodo - ¿sobre que nodo actúa la fuerza?'; 'x y z - ¿cuáles son las componentes de la fuerza?'};
            app.ResolverButton = uibutton(app.UIFigure, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            app.EConstanteCheckBox = uicheckbox(app.UIFigure);
            app.EConstanteCheckBox.ValueChangedFcn = createCallbackFcn(app, @EConstanteCheckBoxValueChanged, true);
            app.EConstanteCheckBox.Text = 'Módulo Elástico Constante';
            app.AConstanteCheckBox = uicheckbox(app.UIFigure);
            app.AConstanteCheckBox.ValueChangedFcn = createCallbackFcn(app, @AConstanteCheckBoxValueChanged, true);
            app.AConstanteCheckBox.Text = 'Área Constante';
            app.EEditFieldLabel = uilabel(app.UIFigure);
            app.EEditFieldLabel.Enable = 'off';
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.UIFigure, 'numeric');
            app.EEditField.ValueChangedFcn = createCallbackFcn(app, @EEditFieldValueChanged, true);
            app.EEditField.Enable = 'off';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Enable = 'off';
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.AEditField.Enable = 'off';
            app.TablaCondiciones = uitable(app.UIFigure);
            app.TablaCondiciones.ColumnName = {'Nodo'; 'x'; 'y'; 'z'};
            app.TablaCondiciones.RowName = {};
            app.TablaCondiciones.ColumnEditable = [false true true true];
            app.TablaCondiciones.Tooltip = {'Especifica si el movimiento de cierto nodo a lo largo de un eje no es posible.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Armaduras_3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end